classdef realTimeMessageRx < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        WindSpeedUIFigure      matlab.ui.Figure
        UITable                matlab.ui.control.Table
        SendDataButton         matlab.ui.control.Button
        ClearDataButton        matlab.ui.control.Button
        StopTrasmitterButton   matlab.ui.control.Button
        DataSendTextArea       matlab.ui.control.TextArea
        DataSendTextAreaLabel  matlab.ui.control.Label
        UITableRaw             matlab.ui.control.Table
        Messaggio              matlab.ui.control.TextArea
        MessageLabel           matlab.ui.control.Label
        StopReceiverButton_2   matlab.ui.control.Button
        StartReceiverButton    matlab.ui.control.Button
        Label                  matlab.ui.control.Label
        BroadcastsystemcommunicationprototypeLabel  matlab.ui.control.Label
    end


    properties (Access = private)
        receiverTimer % Timer object
        test % Description
        Pluto % Description
        preamble % Description
        sps % Description
        span % Description
        beta % Description
        seq_end % Description
        fc % Description
        SamplingRate % Description
        txPluto % Description
        releaseTimer % Description
        timeToExecute % Description
        currentTime % Description
    end

    properties (Access = public)
        seq_in = load('preamble.mat') % Description
        dataToDisplay % Description
        receviverState % Description
    end

    methods (Access = private)

        function receiverFcn(app,src,event)
            % Get current time


            app.timeToExecute= tic;
            app.releaseTimer = app.releaseTimer + 1;

            if app.releaseTimer == 1
                app.txPluto = TX_NO_GRAPH("UwUUwU",1);
            elseif app.releaseTimer == 2
                release(app.txPluto);
                app.DataSendTextArea.Value = '';
            elseif app.releaseTimer == 4
                app.txPluto = TX_NO_GRAPH(app.DataSendTextArea.Value,0);
            end


            %app.Messaggio.Value = sprintf('aaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaaa%.2f',app.test);

            app.timeToExecute = toc(app.timeToExecute)
            %
            % if(app.timeToExecute > 0.4*app.currentTime)
            %     stop(app.receiverTimer);
            %     delete(app.receiverTimer);
            %      app.receiverTimer = timer(...
            %     "ExecutionMode","fixedRate", ...      % Run timer repeatedly
            %     "Period",2.0*app.timeToExecute, ...     % Period is 5 seconds
            %     "BusyMode","queue",...                % Queue timer callbacks when busy
            %     "TimerFcn",@app.receiverFcn);   % Callback that runs every period
            %     app.currentTime = 2*app.timeToExecute;
            %
            %
            %     start(app.receiverTimer);
            % end


        end


    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.test = 0;
            app.releaseTimer = 10;

            app.dataToDisplay = strings(1,256);
            %app.dataToDisplay = strcat(app.dataToDisplay, "c" )

            app.txPluto = TX_NO_GRAPH("waiting new data to be send" , 0);

            % Create timer object
            app.currentTime = 1;
            app.receiverTimer = timer(...
                "ExecutionMode","fixedRate", ...      % Run timer repeatedly
                "Period",1, ...                       % Period is 5 seconds
                "BusyMode","queue",...                % Queue timer callbacks when busy
                "TimerFcn",@app.receiverFcn);   % Callback that runs every period


            start(app.receiverTimer);


            app.receviverState = 1;
            RX_NO_GRAPH(app);


        end

        % Button pushed function: StartReceiverButton
        function StartReceiverButtonPushed(app, event)
            app.receviverState = 1;
            app.Messaggio.Value=erase(app.Messaggio.Value,app.Messaggio.Value);
            RX_NO_GRAPH(app);
        end

        % Button pushed function: StopReceiverButton_2
        function StopReceiverButton_2Pushed(app, event)
            % Stop timer
            app.receviverState = 0;
        end

        % Close request function: WindSpeedUIFigure
        function UIFigureCloseRequest(app, event)
            % Stop timer, then delete timer and figure
            try
                release(app.txPluto);
            catch
            end
            app.receviverState = 0;
            stop(app.receiverTimer);
            delete(app.receiverTimer);
            delete(app);
        end

        % Button pushed function: SendDataButton
        function SendButtonValuePushed(app, event)
            try
                release(app.txPluto);
            catch
            end
            app.releaseTimer = 3;
        end

        % Button pushed function: ClearDataButton
        function ClearDataButtonValueChanged(app, event)
            try
                release(app.txPluto);
            catch
            end
            app.releaseTimer = 0;
        end

        % Button pushed function: StopTrasmitterButton
        function StopTrasmitterButtonValueChanged(app, event)
            try
                release(app.txPluto);
            catch
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.WindSpeedUIFigure = uifigure('Visible', 'off');
            app.WindSpeedUIFigure.Name = 'Wind Speed';
            app.WindSpeedUIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.BroadcastsystemcommunicationprototypeLabel = uilabel(app.WindSpeedUIFigure);
            app.BroadcastsystemcommunicationprototypeLabel.Text = 'Broadcast system communication prototype';
            app.Label = uilabel(app.WindSpeedUIFigure);
            app.Label.Text = '';
            app.StartReceiverButton = uibutton(app.WindSpeedUIFigure, 'push');
            app.StartReceiverButton.ButtonPushedFcn = createCallbackFcn(app, @StartReceiverButtonPushed, true);
            app.StartReceiverButton.Text = 'Start Receiver';
            app.StopReceiverButton_2 = uibutton(app.WindSpeedUIFigure, 'push');
            app.StopReceiverButton_2.ButtonPushedFcn = createCallbackFcn(app, @StopReceiverButton_2Pushed, true);
            app.StopReceiverButton_2.Text = 'Stop Receiver';
            app.MessageLabel = uilabel(app.WindSpeedUIFigure);
            app.MessageLabel.Text = 'Message';
            app.Messaggio = uitextarea(app.WindSpeedUIFigure);
            app.Messaggio.Editable = 'off';
            app.UITableRaw = uitable(app.WindSpeedUIFigure);
            app.UITableRaw.ColumnName = {'TimeStamp'; 'Data'; 'Packet Number'; 'CRC OK'; 'END OK'};
            app.UITableRaw.RowName = {};
            app.DataSendTextAreaLabel = uilabel(app.WindSpeedUIFigure);
            app.DataSendTextAreaLabel.Text = 'Data Send';
            app.DataSendTextArea = uitextarea(app.WindSpeedUIFigure);
            app.StopTrasmitterButton = uibutton(app.WindSpeedUIFigure, 'push');
            app.StopTrasmitterButton.ButtonPushedFcn = createCallbackFcn(app, @StopTrasmitterButtonValueChanged, true);
            app.StopTrasmitterButton.Text = 'Stop Trasmitter';
            app.ClearDataButton = uibutton(app.WindSpeedUIFigure, 'push');
            app.ClearDataButton.ButtonPushedFcn = createCallbackFcn(app, @ClearDataButtonValueChanged, true);
            app.ClearDataButton.Text = 'Clear Data';
            app.SendDataButton = uibutton(app.WindSpeedUIFigure, 'push');
            app.SendDataButton.ButtonPushedFcn = createCallbackFcn(app, @SendButtonValuePushed, true);
            app.SendDataButton.Text = 'Send Data';
            app.UITable = uitable(app.WindSpeedUIFigure);
            app.UITable.ColumnName = {'EVM'; 'MER [dB]'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.WindSpeedUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = realTimeMessageRx

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.WindSpeedUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.WindSpeedUIFigure)
        end
    end
end